classdef runExperiment < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        EnterSubjectIDEditFieldLabel  matlab.ui.control.Label
        EnterSubjectIDEditField       matlab.ui.control.EditField
        BlockLabel                    matlab.ui.control.Label
        RunLabel                      matlab.ui.control.Label
        CompleteLabel                 matlab.ui.control.Label
        LoudnessTestLabel             matlab.ui.control.Label
        ASSRLabel                     matlab.ui.control.Label
        MMNPart1Label                 matlab.ui.control.Label
        MMNPart2Label                 matlab.ui.control.Label
        P50Part1Label                 matlab.ui.control.Label
        P50Part2Label                 matlab.ui.control.Label
        P50Part3Label                 matlab.ui.control.Label
        CASPart1Label                 matlab.ui.control.Label
        CASPart2Label                 matlab.ui.control.Label
        CASPart3Label                 matlab.ui.control.Label
        DurationminutesLabel          matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        Label_3                       matlab.ui.control.Label
        Label_4                       matlab.ui.control.Label
        Label_5                       matlab.ui.control.Label
        Label_6                       matlab.ui.control.Label
        Label_7                       matlab.ui.control.Label
        Label_8                       matlab.ui.control.Label
        Label_9                       matlab.ui.control.Label
        Label_10                      matlab.ui.control.Label
        LoudnessTestStartButton       matlab.ui.control.Button
        ASSRStartButton               matlab.ui.control.Button
        MMNpart1StartButton           matlab.ui.control.Button
        MMNpart2StartButton           matlab.ui.control.Button
        P50part1StartButton           matlab.ui.control.Button
        P50part2StartButton           matlab.ui.control.Button
        P50part3StartButton           matlab.ui.control.Button
        CASpart1StartButton           matlab.ui.control.Button
        CASpart2StartButton           matlab.ui.control.Button
        CASpart3StartButton           matlab.ui.control.Button
        CheckBox                      matlab.ui.control.CheckBox
        CheckBox_2                    matlab.ui.control.CheckBox
        CheckBox_3                    matlab.ui.control.CheckBox
        CheckBox_4                    matlab.ui.control.CheckBox
        CheckBox_5                    matlab.ui.control.CheckBox
        CheckBox_6                    matlab.ui.control.CheckBox
        CheckBox_7                    matlab.ui.control.CheckBox
        CheckBox_8                    matlab.ui.control.CheckBox
        CheckBox_9                    matlab.ui.control.CheckBox
        CheckBox_10                   matlab.ui.control.CheckBox
        ConfirmButton                 matlab.ui.control.Button
        CalculateScoreLabel           matlab.ui.control.Label
        Label_11                      matlab.ui.control.Label
        CalculateScoreButton          matlab.ui.control.Button
        CheckBox_11                   matlab.ui.control.CheckBox
        Panel                         matlab.ui.container.Panel
    end

    
    methods (Access = private)
        
        function runBlock(app, paradigm, blockFilePath)
                        
            currentDir = fileparts(mfilename('fullpath')); % Get the directory of current script
            parentDir = fileparts(currentDir); % Get the parent directory 
            
            % Save the 'paradigm' variable to 'paradigm.mat' to pass the
            % correct paradigm to the main experimentCode script
            save(fullfile(currentDir, 'paradigm.mat'), 'paradigm');
            
            % Get subject ID from UI field
            subjectIDNumber = app.EnterSubjectIDEditField.Value;
            
            % Construct the filename and file path dynamically
            saveFileName = [paradigm 'SavedData' subjectIDNumber '.mat'];
            saveFolderName = ['COMPS' subjectIDNumber];
            saveFilePath = fullfile(parentDir, 'subjectDataSaved', saveFolderName, saveFileName);
            
            % Check if saved data for this subject ID already exists
            if isfile(saveFilePath)
                selection = uiconfirm(app.UIFigure, ...
                    [paradigm ' data for Subject ID ' subjectIDNumber ' already exists. Overwrite?'], ...
                    'Data File Already Exists', ...
                    'Options', {'OK','Cancel'}, ...
                    'DefaultOption', 'Cancel', ...
                    'Icon','warning');
            
                if strcmp(selection, 'Cancel')
                    return;
                end
                % Otherwise continue and potentially overwrite
            end
            
            % Pop up to say chillllll bro so people don't
            % repeatedly click to open the task
            uialert(app.UIFigure, 'Please be patient while task loads, it can sometimes take a few seconds :)', 'Task is loading!', 'Icon', 'information');
            
            run(blockFilePath) % Run the task
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            % Get the value from the text input field
            inputText = app.EnterSubjectIDEditField.Value;
            subjectIDNumber = inputText; 
                        
            % Check if the input is a 4-digit number
            if length(inputText) == 7 && ...
               ~isnan(str2double(inputText(1:4))) && ...
               any(strcmp(inputText(5:7), {'-T0', '-T1'}))

                % Check if data has already been saved in a folder for this subject to
                % avoid overwriting / entering wrong subject ID
                
                % Create folder name for the subject data
                folderName = ['COMPS' inputText]; % change to inputText
                
                % Construct folder path: two folders up, then into 
                basePath = fullfile(fileparts(fileparts(mfilename('fullpath'))), 'subjectData', folderName);
                
                % Check if folder already exists
                if isfolder(basePath)
                    % Warn user that data folder for this subject ID
                    % already exists
                    selection = uiconfirm(app.UIFigure, ...
                        ['Data for Subject ID ' subjectIDNumber ' already exists. Overwrite?'], ...
                        'Data Folder Already Exists', ...
                        'Options', {'OK','Cancel'}, ...
                        'DefaultOption', 'Cancel', ...
                        'Icon','warning');
                    
                    % User can cancel subject ID submissions
                    if strcmp(selection, 'Cancel')
                        return;  
                    end
                    % Or continue to potentially overwrite old data
                end
                
                % Valid 4-digit number: Unhide the panel and disable the input field
                app.EnterSubjectIDEditField.Enable = 'off';        % Disable the input field
                app.ConfirmButton.Enable = 'off';      % Disable the submit button after a valid entry
                                                                
                % Saving the subject ID number
                
                save(fullfile(fileparts(mfilename('fullpath')), 'subjectIDNumber.mat'), 'subjectIDNumber');
                
                % Hide panel so rest of GUI becomes visible
                
                app.Panel.Visible = 'off';

            else
                % Invalid input: Show an alert with a warning message
                uialert(app.UIFigure, 'Please enter a valid ID e.g. 0007-T0', 'Invalid Input', 'Icon', 'error');
            end
        end

        % Button pushed function: LoudnessTestStartButton
        function LoudnessTestStartButtonPushed(app, event)
            paradigm = "loudnessTest";
            blockFilePath = fullfile('loudnessTest', 'runLoudnessTest.m'); 
            runBlock(app, paradigm, blockFilePath)
        end

        % Button pushed function: ASSRStartButton
        function ASSRStartButtonPushed(app, event)
            paradigm = "ASSR";
            blockFilePath = fullfile('experimentCode.m'); 
            runBlock(app, paradigm, blockFilePath)
        end

        % Button pushed function: MMNpart1StartButton
        function MMNpart1StartButtonPushed(app, event)
            paradigm = "MMNPart1";
            blockFilePath = fullfile('experimentCode.m');
            runBlock(app, paradigm, blockFilePath)
        end

        % Button pushed function: MMNpart2StartButton
        function MMNpart2StartButtonPushed(app, event)
            paradigm = "MMNPart2";
            blockFilePath = fullfile('experimentCode.m');
            runBlock(app, paradigm, blockFilePath)
        end

        % Button pushed function: P50part1StartButton
        function P50part1StartButtonPushed(app, event)
            paradigm = "P50Part1";
            blockFilePath = fullfile('experimentCode.m');
            runBlock(app, paradigm, blockFilePath)
        end

        % Button pushed function: P50part2StartButton
        function P50part2StartButtonPushed(app, event)
            paradigm = "P50Part2";
            blockFilePath = fullfile('experimentCode.m');
            runBlock(app, paradigm, blockFilePath)
        end

        % Button pushed function: P50part3StartButton
        function P50part3StartButtonPushed(app, event)
            paradigm = "P50Part3";
            blockFilePath = fullfile('experimentCode.m');
            runBlock(app, paradigm, blockFilePath)
        end

        % Button pushed function: CASpart1StartButton
        function CASpart1StartButtonPushed(app, event)
            paradigm = "CASPart1";
            blockFilePath = fullfile('experimentCode.m');
            runBlock(app, paradigm, blockFilePath)
        end

        % Button pushed function: CASpart2StartButton
        function CASpart2StartButtonPushed(app, event)
            paradigm = "CASPart2";
            blockFilePath = fullfile('experimentCode.m');
            runBlock(app, paradigm, blockFilePath)
        end

        % Button pushed function: CASpart3StartButton
        function CASpart3StartButtonPushed(app, event)
            paradigm = "CASPart3";
            blockFilePath = fullfile('experimentCode.m');
            runBlock(app, paradigm, blockFilePath)                  
        end

        % Button pushed function: CalculateScoreButton
        function CalculateScoreButtonPushed(app, event)
            
            % Get task file location
            current_dir = fileparts(mfilename('fullpath')); 
            filePath = fullfile(current_dir,'\calculateTotalScore.m');
                        
            % Check file exists
            if isfile(filePath)
                                
                run(filePath) % Run the task
                                    
            else 
                uialert(app.UIFigure, 'Cannot find calc score file :(', 'ERROR!')
            end
            
        end

        % Size changed function: Panel
        function PanelSizeChanged(app, event)
            position = app.Panel.Position;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.EnterSubjectIDEditFieldLabel = uilabel(app.UIFigure);
            app.EnterSubjectIDEditFieldLabel.Text = 'Enter Subject ID';
            app.EnterSubjectIDEditField = uieditfield(app.UIFigure, 'text');
            app.EnterSubjectIDEditField.Value = 'e.g. 0007-T0';
            app.BlockLabel = uilabel(app.UIFigure);
            app.BlockLabel.Text = 'Block';
            app.RunLabel = uilabel(app.UIFigure);
            app.RunLabel.Text = 'Run';
            app.CompleteLabel = uilabel(app.UIFigure);
            app.CompleteLabel.Text = 'Complete?';
            app.LoudnessTestLabel = uilabel(app.UIFigure);
            app.LoudnessTestLabel.Text = 'Loudness Test';
            app.ASSRLabel = uilabel(app.UIFigure);
            app.ASSRLabel.Text = 'ASSR';
            app.MMNPart1Label = uilabel(app.UIFigure);
            app.MMNPart1Label.Text = 'MMN Part 1';
            app.MMNPart2Label = uilabel(app.UIFigure);
            app.MMNPart2Label.Text = 'MMN Part 2';
            app.P50Part1Label = uilabel(app.UIFigure);
            app.P50Part1Label.Text = 'P50 Part 1';
            app.P50Part2Label = uilabel(app.UIFigure);
            app.P50Part2Label.Text = 'P50 Part 2';
            app.P50Part3Label = uilabel(app.UIFigure);
            app.P50Part3Label.Text = 'P50 Part 3';
            app.CASPart1Label = uilabel(app.UIFigure);
            app.CASPart1Label.Text = 'CAS Part 1';
            app.CASPart2Label = uilabel(app.UIFigure);
            app.CASPart2Label.Text = 'CAS Part 2';
            app.CASPart3Label = uilabel(app.UIFigure);
            app.CASPart3Label.Text = 'CAS Part 3';
            app.DurationminutesLabel = uilabel(app.UIFigure);
            app.DurationminutesLabel.Text = 'Duration (minutes)';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '5';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '5';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '7';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '8';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '6';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '6';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '8';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '8';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '8';
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '8';
            app.LoudnessTestStartButton = uibutton(app.UIFigure, 'push');
            app.LoudnessTestStartButton.ButtonPushedFcn = createCallbackFcn(app, @LoudnessTestStartButtonPushed, true);
            app.LoudnessTestStartButton.Text = 'Start!';
            app.ASSRStartButton = uibutton(app.UIFigure, 'push');
            app.ASSRStartButton.ButtonPushedFcn = createCallbackFcn(app, @ASSRStartButtonPushed, true);
            app.ASSRStartButton.Text = 'Start!';
            app.MMNpart1StartButton = uibutton(app.UIFigure, 'push');
            app.MMNpart1StartButton.ButtonPushedFcn = createCallbackFcn(app, @MMNpart1StartButtonPushed, true);
            app.MMNpart1StartButton.Text = 'Start!';
            app.MMNpart2StartButton = uibutton(app.UIFigure, 'push');
            app.MMNpart2StartButton.ButtonPushedFcn = createCallbackFcn(app, @MMNpart2StartButtonPushed, true);
            app.MMNpart2StartButton.Text = 'Start!';
            app.P50part1StartButton = uibutton(app.UIFigure, 'push');
            app.P50part1StartButton.ButtonPushedFcn = createCallbackFcn(app, @P50part1StartButtonPushed, true);
            app.P50part1StartButton.Text = 'Start!';
            app.P50part2StartButton = uibutton(app.UIFigure, 'push');
            app.P50part2StartButton.ButtonPushedFcn = createCallbackFcn(app, @P50part2StartButtonPushed, true);
            app.P50part2StartButton.Text = 'Start!';
            app.P50part3StartButton = uibutton(app.UIFigure, 'push');
            app.P50part3StartButton.ButtonPushedFcn = createCallbackFcn(app, @P50part3StartButtonPushed, true);
            app.P50part3StartButton.Text = 'Start!';
            app.CASpart1StartButton = uibutton(app.UIFigure, 'push');
            app.CASpart1StartButton.ButtonPushedFcn = createCallbackFcn(app, @CASpart1StartButtonPushed, true);
            app.CASpart1StartButton.Text = 'Start!';
            app.CASpart2StartButton = uibutton(app.UIFigure, 'push');
            app.CASpart2StartButton.ButtonPushedFcn = createCallbackFcn(app, @CASpart2StartButtonPushed, true);
            app.CASpart2StartButton.Text = 'Start!';
            app.CASpart3StartButton = uibutton(app.UIFigure, 'push');
            app.CASpart3StartButton.ButtonPushedFcn = createCallbackFcn(app, @CASpart3StartButtonPushed, true);
            app.CASpart3StartButton.Text = 'Start!';
            app.CheckBox = uicheckbox(app.UIFigure);
            app.CheckBox.Text = '';
            app.CheckBox_2 = uicheckbox(app.UIFigure);
            app.CheckBox_2.Text = '';
            app.CheckBox_3 = uicheckbox(app.UIFigure);
            app.CheckBox_3.Text = '';
            app.CheckBox_4 = uicheckbox(app.UIFigure);
            app.CheckBox_4.Text = '';
            app.CheckBox_5 = uicheckbox(app.UIFigure);
            app.CheckBox_5.Text = '';
            app.CheckBox_6 = uicheckbox(app.UIFigure);
            app.CheckBox_6.Text = '';
            app.CheckBox_7 = uicheckbox(app.UIFigure);
            app.CheckBox_7.Text = '';
            app.CheckBox_8 = uicheckbox(app.UIFigure);
            app.CheckBox_8.Text = '';
            app.CheckBox_9 = uicheckbox(app.UIFigure);
            app.CheckBox_9.Text = '';
            app.CheckBox_10 = uicheckbox(app.UIFigure);
            app.CheckBox_10.Text = '';
            app.ConfirmButton = uibutton(app.UIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm!';
            app.CalculateScoreLabel = uilabel(app.UIFigure);
            app.CalculateScoreLabel.Text = 'Calculate Score';
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = '0';
            app.CalculateScoreButton = uibutton(app.UIFigure, 'push');
            app.CalculateScoreButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateScoreButtonPushed, true);
            app.CalculateScoreButton.Text = 'Start!';
            app.CheckBox_11 = uicheckbox(app.UIFigure);
            app.CheckBox_11.Text = '';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.SizeChangedFcn = createCallbackFcn(app, @PanelSizeChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = runExperiment

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end